classdef UI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        GridLayout   matlab.ui.container.GridLayout
        LeftPanel    matlab.ui.container.Panel
        StartButton  matlab.ui.control.Button
        RightPanel   matlab.ui.container.Panel
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc;
            disp('Wilkommen zum Optimal Control Simulator ');
            disp('Um zu beginnen drücke auf Start');
            global reset
            reset = 0;
          
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            
            global reset;
            if ~reset
            clc;
            disp('Bitte geben Sie ihren Namen in die Kommandozeile ein');
            fprintf('und drücken Sie danach auf Enter ');
            txt = input(':','s');
            name_person = txt;
            close all
            clc
            disp('Bitte wählen Sie Punkte auf der Karte aus.');
            disp('Das Auto wird versuchen einen Pfad durch die gegebenen');
            disp('Punkte zu finden.')
            disp('--------------------------');
            disp('Wenn Sie fertig sind drücken Sie Enter um den ');
            disp('Optimierungs-Prozess zu starten.')
            
            
            first();
          
            clc;
            second(name_person);
            disp('Erstellen des Videos abgeschlossen.');
            third(name_person);
%             b = uibutton(app.UIFigure);
            
            
%             b.ButtonPushedFcn = @ third;
  
%             b.Position = [19 600 164 84];
%             b.Text = 'Replay Video';

            app.StartButton.Text = 'Reset';
            reset = 1;
            else
            app.StartButton.Text = 'Start';
            clc;
            disp('Wilkommen zum Optimal Control Simulator ');
            disp('Um zu beginnen drücke auf Start');
            reset = 0;
            end


        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {823, 823};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {142, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.StartButton = uibutton(app.LeftPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.RightPanel = uipanel(app.GridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end