classdef UI_converted < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        RightPanel   matlab.ui.container.Panel
        LeftPanel    matlab.ui.container.Panel
        StartButton  matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc;
            disp('Wilkommen zum Optimal Control Simulator ');
            disp('Um zu beginnen drücke auf Start');
            global reset
            reset = 0;
          
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            
            global reset;
            if ~reset
                clc;
                disp('Bitte geben deinen Namen in die Kommandozeile ein');
                fprintf('und drücke danach auf Enter');
                txt = input(': ','s');
                name_person = txt;
                close all
                clc
                disp('Bitte wähle Punkte auf der Karte aus.');
                disp('Das Auto wird versuchen, einen Pfad durch die gegebenen');
                disp('Punkte zu finden.')
                disp('--------------------------');
                disp('Wenn du fertig bist, drücke Enter um den ');
                disp('Optimierungs-Prozess zu starten.')
                
                
                first();
              
                clc;
                problemInfeasible = second(name_person);
                if problemInfeasible==0
                    disp('Erstellen des Videos abgeschlossen.');
                    third(name_person);
                    % b = uibutton(app.UIFigure);  
                    % b.ButtonPushedFcn = @ third;      
                    % b.Position = [19 600 164 84];
                    % b.Text = 'Replay Video';    
                else
                    disp('Drücke auf Reset um von Vorne zu beginnen')
                    disp('------------------------------')  
                end
                app.StartButton.Text = 'Reset';
                reset = 1;
            else
                app.StartButton.Text = 'Start';
                clc;
                disp('Wilkommen zum Optimal Control Simulator ');
                disp('Um zu beginnen drücke auf Start');
                reset = 0;
            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LeftPanel = uipanel(app.UIFigure);
            app.StartButton = uibutton(app.LeftPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.RightPanel = uipanel(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UI_converted

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end